% description: BoneQuantMRI is a lightweight research tool for analyzing bone MRI scans using ROI-based segmentation and phantom-based calibration. Designed for flexible inspection and quantitative
% evaluation of trabecular, cortical, and growth plate regions.
% Instructions:
% Load your MRI scan and optional B1 correction image to begin. Navigate, segment, and analyze regions of interest. Export summary statistics for each region.
% License:
% For non-commercial academic research use only.
% Redistribution or clinical application is not permitted.
% © 2025 Matthias Walle
% Nazarian Lab, Beth Israel Medical Center, Harvard Medical School.
classdef qMRIBone < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BoneQuantMRIUIFigure       matlab.ui.Figure
        BoneQuantMRILabel          matlab.ui.control.Label
        Image3                     matlab.ui.control.Image
        Image2                     matlab.ui.control.Image
        UITable                    matlab.ui.control.Table
        TabGroup                   matlab.ui.container.TabGroup
        DataTab                    matlab.ui.container.Tab
        Step2SelectB1CorrectionImageifrequiredLabel  matlab.ui.control.Label
        Step1SelectMRIImageLabel   matlab.ui.control.Label
        SelectB1CorrectionLabel    matlab.ui.control.Label
        SelectRawImageLabel        matlab.ui.control.Label
        Button_Load_B1             matlab.ui.control.Button
        Button_Load_Raw            matlab.ui.control.Button
        SliceBrowserTab            matlab.ui.container.Tab
        Step4Usethe3DImagebuttontocheckhowtheImagelooksin3DLabel  matlab.ui.control.Label
        Step3UseSliceBrowsertoNavigatethrough3DMRIimageLabel  matlab.ui.control.Label
        SliceSlider                matlab.ui.control.Slider
        SliceSliderLabel           matlab.ui.control.Label
        DButton                    matlab.ui.control.Button
        ViewDropDown               matlab.ui.control.DropDown
        ButtonPrev                 matlab.ui.control.Button
        ButtonNext                 matlab.ui.control.Button
        SegentationTab             matlab.ui.container.Tab
        Step4Usethe3DImagebuttontocheckhowtheImagelooksin3DLabel_3  matlab.ui.control.Label
        Step4Usethe3DImagebuttontocheckhowtheImagelooksin3DLabel_2  matlab.ui.control.Label
        Step3UseSliceBrowsertoNavigatethrough3DMRIimageLabel_2  matlab.ui.control.Label
        ButtonNext_2               matlab.ui.control.Button
        ButtonPrev_2               matlab.ui.control.Button
        ExitButton                 matlab.ui.control.Button
        NewROIButton               matlab.ui.control.Button
        ROIButtonGroup             matlab.ui.container.ButtonGroup
        PhantomButton              matlab.ui.control.RadioButton
        BoneButton                 matlab.ui.control.RadioButton
        OperationButtonGroup       matlab.ui.container.ButtonGroup
        RemoveButton               matlab.ui.control.RadioButton
        AddButton                  matlab.ui.control.RadioButton
        ThresholdLabel             matlab.ui.control.Label
        autoCheckBox               matlab.ui.control.CheckBox
        Slider_Threshold           matlab.ui.control.Slider
        LoadContButton             matlab.ui.control.Button
        SaveContButton             matlab.ui.control.Button
        ResegmentButton            matlab.ui.control.Button
        ROITab                     matlab.ui.container.Tab
        Step4Usethe3DImagebuttontocheckhowtheImagelooksin3DLabel_6  matlab.ui.control.Label
        Step4Usethe3DImagebuttontocheckhowtheImagelooksin3DLabel_5  matlab.ui.control.Label
        Step4Usethe3DImagebuttontocheckhowtheImagelooksin3DLabel_4  matlab.ui.control.Label
        Step3UseSliceBrowsertoNavigatethrough3DMRIimageLabel_3  matlab.ui.control.Label
        ROILabel                   matlab.ui.control.Label
        SaveButton                 matlab.ui.control.Button
        LoadButton                 matlab.ui.control.Button
        Button_Prev_startCort      matlab.ui.control.Button
        Button_Prev_stopCort       matlab.ui.control.Button
        Button_Prev_stopTrab       matlab.ui.control.Button
        Button_Prev_startTrab      matlab.ui.control.Button
        Button_Next_stopTrab       matlab.ui.control.Button
        Button_Next_startTrab      matlab.ui.control.Button
        Button_Next_stopCort       matlab.ui.control.Button
        Button_Next_startCort      matlab.ui.control.Button
        GuessButton                matlab.ui.control.Button
        CorticalLabel              matlab.ui.control.Label
        TrabecularLabel            matlab.ui.control.Label
        Slider_Stop_Cortical       matlab.ui.control.Slider
        Slider_Start_Cortical      matlab.ui.control.Slider
        Slider_Stop_Trabecular     matlab.ui.control.Slider
        Slider_Start_Trabecular    matlab.ui.control.Slider
        DataProcessingTab          matlab.ui.container.Tab
        ndflipDropDown             matlab.ui.control.DropDown
        ndflipDropDownLabel        matlab.ui.control.Label
        FlipB1DropDown             matlab.ui.control.DropDown
        FlipB1DropDownLabel        matlab.ui.control.Label
        SmoothDropDown             matlab.ui.control.DropDown
        SmoothDropDownLabel        matlab.ui.control.Label
        DCMCheckBox                matlab.ui.control.CheckBox
        MRIderiveddensityCheckBox  matlab.ui.control.CheckBox
        cmaplim                    matlab.ui.control.CheckBox
        cmapmax                    matlab.ui.control.EditField
        cmapmin                    matlab.ui.control.EditField
        figButton                  matlab.ui.control.Button
        CheckBox                   matlab.ui.control.CheckBox
        SegmentationButtonGroup    matlab.ui.container.ButtonGroup
        off_seg                    matlab.ui.control.RadioButton
        on_seg                     matlab.ui.control.RadioButton
        Step4Usethe3DImagebuttontocheckhowtheImagelooksin3DLabel_8  matlab.ui.control.Label
        Step3UseSliceBrowsertoNavigatethrough3DMRIimageLabel_4  matlab.ui.control.Label
        CmapDropDown               matlab.ui.control.DropDown
        CmapDropDownLabel          matlab.ui.control.Label
        EnhanceDisplayButtonGroup  matlab.ui.container.ButtonGroup
        off_enhance                matlab.ui.control.RadioButton
        on_enhance                 matlab.ui.control.RadioButton
        ExportDICOMButtonGroup     matlab.ui.container.ButtonGroup
        NoiseCaliCheckBox          matlab.ui.control.CheckBox
        PhantomCaliCheckBox        matlab.ui.control.CheckBox
        B1CorrectedCheckBox        matlab.ui.control.CheckBox
        ContouredCheckBox          matlab.ui.control.CheckBox
        ExportButton               matlab.ui.control.Button
        B1CorrectionButtonGroup    matlab.ui.container.ButtonGroup
        off_b1                     matlab.ui.control.RadioButton
        on_b1                      matlab.ui.control.RadioButton
        CalibrPhantomButtonGroup   matlab.ui.container.ButtonGroup
        noise                      matlab.ui.control.CheckBox
        off_cal                    matlab.ui.control.RadioButton
        on_cal                     matlab.ui.control.RadioButton
        ThrEditField               matlab.ui.control.EditField
        ThrEditFieldLabel          matlab.ui.control.Label
        DensityEditField           matlab.ui.control.EditField
        DensityEditFieldLabel      matlab.ui.control.Label
        AutoButton                 matlab.ui.control.Button
        SingleButton               matlab.ui.control.Button
        MRIAnalysisResultsLabel    matlab.ui.control.Label
        UI_res                     matlab.ui.control.UIAxes
        UI_b1                      matlab.ui.control.UIAxes
        UI_raw                     matlab.ui.control.UIAxes
    end

    % |**********************************************************************;
    % * Project           : MRI Bone Imaging
    % *
    % * Institute         : Beth Israel Medical Deaconess Center, Boston
    % *
    % * Author            : mwalle (m.walle@tum.de)
    % *
    % * Date created      : 20191203
    % *
    % * Purpose           : GUI to process bone 64x64x64 MRI images.
    %                           - Segmentation Tool
    %                           - B1 Correction (Accounts for Local MRI
    %                           inhomogenities based on reference image
    %                           - Global correction based on reference Phantom
    %                           within image
    %                           - ROI based on microCT slice data (Improvement
    %                           would be a not hard coded version)
    % *
    % * Revision History  : v1 Initial Release
    %                       v2 ...
    % *
    % * Date        Author      Ref    Revision (Date in YYYYMMDD format)
    % * 20191203    mwalle      1      Initial Release based on ROIanalyzer (jegan).
    % *
    % |**********************************************************************;
    % Notes: Dicoms are saved as int16 (signed)
    properties (Access = private)
        %% Variables Initialization
        
        %% 3D Image Variables
        raw3D=zeros(64,64,64)           % 3D Raw Bone Image from DICOM
        mod3D=zeros(64,64,64)           % 3D Modified image (B1 + Segmentation)
        msk3D=zeros(64,64,64)           % ROI Bone and Phantom used for GUI Slider min/max values
        mskbone3D=zeros(64,64,64)       % Bone extracted from msk3D (modified during Segmentation)
        mskphantom3D=zeros(64,64,64)    % Phantom extracted from msk3D (modified during Segmentation)
        b13D=double(ones(64,64,64))     % 3D B1 Correction Image
        b1matr=double(ones(64,64,64));  % 3D B1 Correction Matrix (Segmented, Inverse B1 Image)
        mod3Dno_seg                     % Additional mod3D image, with no segmentation applyed (this was added)
        %% 2D Image Variables
        raw2D=zeros(64,64)              % 2D Slice According to 3D viewer changes are always pushed to 3D matrix
        mod2D=zeros(64,64)              % 2D Slice According to 3D viewer changes are always pushed to 3D matrix
        msk2D=zeros(64,64)              % 2D Slice According to 3D viewer changes are always pushed to 3D matrix
        mskbone2D=zeros(64,64)          % 2D Slice According to 3D viewer changes are always pushed to 3D matrix
        mskphantom2D=zeros(64,64)       % 2D Slice According to 3D viewer changes are always pushed to 3D matrix
        b12D=double(ones(64,64))        % 2D Slice According to 3D viewer changes are always pushed to 3D matrix
        
        %% ROI processing Variables
        pos_roi_bone=cell(0)            % Interactive ROI cell array for "Add Bone"
        neg_roi_bone=cell(0)            % Interactive ROI cell array for "Remove Bone"
        pos_roi_phantom=cell(0)         % Interactive ROI cell array for "Add Phantom"
        neg_roi_phantom=cell(0)         % Interactive ROI cell array for "Remove Phantom"
        start_bone=0                    % Start of bone identifier
        
        %% Graphic Objects
        fig_seg                         % Figure Handle for Segmentation (UIaxis not supported yet)
        ax                              % Axis Hanlde for Segmentation (UIaxis not supported yet)
        l1                              % line object ROI start of trabecular region
        l2                              % line object ROI end of trabecular region
        l3                              % line object ROI start of cortical region
        l4                              % line object ROI end of cortical region
        l5                              % line object ROI start of bone
        
        mri_res = 0.4688;               %in mm MRI Resolution
        mm_distance_growth_plates=6;    %in mm
        mm_size_trab_region=4;          % in mm
        mm_size_cort_region=4;          % in mm
        mm_size_gap_region=0;           % in mm
    end
    
    methods (Access = private)
        % Import DiCOM images
        function image = importDICOMdata(app)
            % Get Directory
            selpath = uigetdir;
            listing = dir(selpath);
            info = dicominfo([selpath '/' listing(4).name]);
            
            % Dialogue message for UI
            f=uifigure();
            d = uiprogressdlg(f,'Title','Please Wait',...
                'Message','Opening the application');
            pause(.5)
            d.Value = .33;
            d.Message = 'Loading your data';
            
            % Flip image if orientation is faulty
            image=squeeze(dicomreadVolume(selpath));
            if info.PatientPosition~="HFS"
                image = flip(image,2);
            end
            d.Value = 1;
            
            
            % Switch to figure
            close(f);
            figure(app.BoneQuantMRIUIFigure);
            
        end
        
        % Seperate ROI in Phantom and Bone masks
        function [msk, tmp_B, tmp_Ph] = seperateROI(app,image)
            % adjust image for better thresholding
            adj_im=imadjustn(image);
            
            % Decide between manual and automatic threshold based on GUI
            if app.autoCheckBox.Value==1
                %Otsu's Method to calculate threshold
                T = graythresh(adj_im);
                app.Slider_Threshold.Value=graythresh(adj_im);
            else
                T = app.Slider_Threshold.Value;
            end
            
            % Create a mask by binarizing according to threshold, and removing objects smaller than 10 pixels
            msk=bwareaopen(imbinarize(adj_im,T),10);
            
            % Erode mask by 2 voxel in order to make sure that bone and
            % phantom region are seperated during automated segmentation.
            SE= strel('sphere', 2);
            temp3D=imerode(msk,SE);
            
            % get Centroid and Voxellist of two ROI (Bone, Phantom)
            regionstats = regionprops3(temp3D,"Centroid","VoxelIdxList");
            [~, indx] = sort(regionstats.Centroid(:,2),"descend");
            
            if height(regionstats)>2
                warning('Unconnected Regions found. Consider lowering the threshold or using autothreshold')
            elseif height(regionstats)<2
                warning('Only one ROI found. Phantom and Bone region seem to be connected. Increase Threshold manually.')
            end
            
            % create a binary mask for the phantom.
            tmp_Ph=zeros(size(image));
            tmp_Ph(regionstats.VoxelIdxList{indx(1)})=1;
            
            % dilate this mask by 3 voxel (as we eroded 2 earlier this
            % should include all phantom voxel.
            SE= strel('sphere', 3);
            tmp_Ph = imdilate(tmp_Ph,SE);
            
            % delete everything not included in the
            % original mask to get the orignal shape of the phantom.
            tmp_Ph(~msk)=0;
            
            % bone mask everything not included in phantom mask.
            tmp_B= msk & ~tmp_Ph;
            
            % remove small objects
            tmp_B= double(bwareaopen(tmp_B,100));
            
            % get MRI data from ROI and calculate boundingbox
            mri_box=regionprops(tmp_B,'BoundingBox','PixelIdxList');
            
            % Define Start of bone depending on smallest bounding box
            app.start_bone = round(mri_box(1).BoundingBox(2)+mri_box(1).BoundingBox(5)); % long axis is y axis
            
        end
        
        % plot boundaries of ROI based on binary mask.
        function plotROIasLine(~,mask,axobj,col)
            hold( axobj, 'on' );
            [B,~,N,A] = bwboundaries(mask);
            % Loop through object boundaries
            for kk = 1:N
                % Boundary k is the parent of a hole if the k-th column
                % of the adjacency matrix A contains a non-zero element
                if (nnz(A(:,kk)) > 0)
                    boundary = B{kk};
                    plot(axobj, boundary(:,2),boundary(:,1),col(1),'LineWidth',2);
                    
                    % Loop through the children of boundary k
                    for ll = find(A(:,kk))'
                        % This identifies positive outlines
                        boundary = B{ll};
                        plot(axobj, boundary(:,2), boundary(:,1),col(2),'LineWidth',2);
                    end
                else
                    % This identifies holes or negative outlines
                    boundary = B{kk};
                    plot(axobj, boundary(:,2),boundary(:,1),col(1),'LineWidth',2);
                end
            end
            hold( axobj, 'off' );
        end
        
        % Function to update 2D Matrices based on 3D Data and refresh the
        % figure handles. Self explanotory
        function updateSlice(app)
            updateResIm(app)
            CurSlice= int32(app.SliceSlider.Value);
            switch app.ViewDropDown.Value
                case 'Saggital'
                    app.mod2D =     squeeze(app.mod3D(:,:,CurSlice));
                    app.raw2D =     squeeze(app.raw3D(:,:,CurSlice));
                    app.msk2D =     squeeze(app.msk3D(:,:,CurSlice));
                    app.b12D =      squeeze(app.b13D(:,:,CurSlice));
                    app.mskbone2D = squeeze(app.mskbone3D(:,:,CurSlice));
                    app.mskphantom2D = squeeze(app.mskphantom3D(:,:,CurSlice));
                    mod2Dno_seg     = squeeze(app.mod3Dno_seg(:,:,CurSlice));
                case 'Coronal'
                    app.mod2D =     squeeze(app.mod3D(:,CurSlice,:));
                    app.raw2D =     squeeze(app.raw3D(:,CurSlice,:));
                    app.msk2D =     squeeze(app.msk3D(:,CurSlice,:));
                    app.b12D =      squeeze(app.b13D(:,CurSlice,:));
                    app.mskbone2D = squeeze(app.mskbone3D(:,CurSlice,:));
                    app.mskphantom2D = squeeze(app.mskphantom3D(:,CurSlice,:));
                    mod2Dno_seg     = squeeze(app.mod3Dno_seg(:,CurSlice,:));
                    
                case 'Axial'
                    app.mod2D = squeeze(app.mod3D(CurSlice,:,:));
                    app.raw2D = squeeze(app.raw3D(CurSlice,:,:));
                    app.msk2D = squeeze(app.msk3D(CurSlice,:,:));
                    app.b12D =squeeze(app.b13D(CurSlice,:,:));
                    app.mskbone2D = squeeze(app.mskbone3D(CurSlice,:,:));
                    app.mskphantom2D = squeeze(app.mskphantom3D(CurSlice,:,:));
                    mod2Dno_seg     = squeeze(app.mod3Dno_seg(CurSlice,:,:));
                    
                otherwise
            end
            
            % Plot adjusted versions of the images jet colormap feels the
            % most constrasty. Important: ONLY the display is adjusted,
            % values are not actually changed.
            
            if app.noise.Value
                noise=double(mean(app.raw3D(~app.mskbone3D & ~app.mskphantom3D)));
            else
                noise=0;
            end
            
            m1=mean(app.raw3D(app.mskphantom3D>0));
            m2=str2double(app.ThrEditField.Value);
            
            
            if app.on_cal.Value
                s=m1-noise;
            else
                s=m2-noise;
            end
            scale=s/max([m1 m2]);


            raw=uint16(double(app.raw2D)./double(max(app.raw3D(:))).*double(intmax('uint16')));
            mod=uint16(double(app.mod2D).*scale./double(max(app.mod3D(:))).*double(intmax('uint16')));
            b1=uint16(double(app.b12D)./double(max(app.b13D(:))).*double(intmax('uint16')));
            if app.on_seg.Value==0
                mod=uint16(double(mod2Dno_seg).*scale./double(max(app.mod3Dno_seg(:))).*double(intmax('uint16')));
            end
            
            if app.on_enhance.Value==1
                raw=imadjust(raw);
                mod=imadjust(mod);
                b1=imadjust(b1);
            end
                        

            imshow(raw, 'Parent', app.UI_raw);
            colormap(app.UI_raw,app.CmapDropDown.Value);
            axis(app.UI_raw, 'equal');
            app.UI_raw.Visible='On';
            
            imshow(mod, 'Parent', app.UI_res);
            colormap(app.UI_res,app.CmapDropDown.Value);
            axis(app.UI_res, 'equal');
            app.UI_res.Visible='On';
            
            if app.cmaplim.Value==0
                caxis(app.UI_res,'auto')
                cl = caxis(app.UI_res);
                app.cmapmin.Value=mat2str(cl(1));
                app.cmapmax.Value=mat2str(cl(2));
            else
                caxis(app.UI_res,'manual');
                cl(1)=str2double(app.cmapmin.Value);
                cl(2)=str2double(app.cmapmax.Value);
                caxis(app.UI_res,cl)
            end
            
            if app.CheckBox.Value
                colorbar(app.UI_res,'east','color','white');
            else
                colorbar(app.UI_res, 'off' )
            end
            
            
            imshow(b1, 'Parent', app.UI_b1);
            colormap(app.UI_b1,app.CmapDropDown.Value);
            axis(app.UI_b1, 'equal');
            app.UI_b1.Visible='On';
            
            % Plot ROI as an outline
            plotROIasLine(app,app.mskbone2D,app.UI_raw,['g';'r']);
            plotROIasLine(app,app.mskphantom2D,app.UI_raw,['b';'k']);
            
        end
        
        % Push sliced image to 3D matrix
        function tmp3D = sliceto3D(~,tmp3D,tmp2D,CurSlice,Orientation)
            % This function adjusts 3D matrices according to current Slice
            switch Orientation
                case 'Saggital'
                    tmp3D(:,:,CurSlice)=tmp2D;
                case 'Coronal'
                    tmp3D(:,CurSlice,:)=tmp2D;
                case 'Axial'
                    tmp3D(CurSlice,:,:)=tmp2D;
                otherwise
            end
            
        end
        
        % Function to create an interactive ROI based on a binary mask.
        % only works with axisobj not uiaxisobj.
        function [pos_roi, neg_roi] = mask2interactiveROI(~,mask2D,axisobj,col)
            pos_roi=cell(0);
            neg_roi=cell(0);
            
            axes(axisobj)
            hold on;
            [B,~,N,A] = bwboundaries(mask2D);
            % Loop through object boundaries
            for kk = 1:N
                % Boundary k is the parent of a hole if the k-th column
                % of the adjacency matrix A contains a non-zero element
                if (nnz(A(:,kk)) > 0)
                    boundary = B{kk};
                    pos_roi{kk} = drawpolygon(axisobj,'Position',floor([boundary(:,2) boundary(:,1)]),'Color',col(1));
                    % Loop through the children of boundary k
                    for ll = find(A(:,kk))'
                        boundary = B{ll};
                        neg_roi{kk} = drawpolygon(axisobj,'Position',floor([boundary(:,2) boundary(:,1)]),'Color',col(2));
                    end
                else
                    boundary = B{kk};
                    pos_roi{kk} = drawpolygon(axisobj,'Position',floor([boundary(:,2) boundary(:,1)]),'Color',col(1));
                    
                end
            end
            hold off;
        end
        
        % Deletes all ROI in a cell array.
        function deleteroi(~,inp)
            for i=1:length(inp)
                delete(inp{i});
            end
        end
        
        % Updates the resulting image based on ROI and B1
        function updateResIm(app)
            
            % Decide on B1 Correction Option
            if app.on_b1.Value
                b1loc=app.b1matr;
            else
                b1loc=ones(size(app.b1matr));
            end
            b1_im=double(app.raw3D).*double(b1loc);
            
            % Local correction using pellet density (UI) Comparing actual density of
            % pellet in image to reference density
            if app.on_cal.Value
                density_is= double(mean(b1_im(app.mskphantom3D>0)));
            else
                density_is= str2double(app.ThrEditField.Value);
            end
            % Decide to include noise adaption or not
            if app.noise.Value
                noise=double(mean(app.raw3D(~app.mskbone3D & ~app.mskphantom3D)));
            else
                noise=double(0);
                
            end
            
            % Shift intensity according to noise, and then scale linear
            % with reference according to two point calibration:
            % ->CorrectedValue = (((RawValue – RawLow) * ReferenceRange) / RawRange) + ReferenceLow
            % with ReferenceLow= 0.
            density_aim= str2double(app.DensityEditField.Value);
             
            % it is new to have no correction for b1 image exports. 
            if ~app.on_cal.Value && ~app.noise.Value
                corr_fact=1;
                disp('no correction (corr_fact=1)')
            else
                corr_fact=density_aim./(density_is-noise);
            end
                 
            b1_cal_im=(b1_im-noise).*corr_fact;
            
   
            % Smoothing and filtering for visuals 
            if strcmp(app.SmoothDropDown.Value,'None')
                b1_cal_im_smooth=b1_cal_im;
            elseif strcmp(app.SmoothDropDown.Value,'Gauss')
                b1_cal_im_smooth=smooth3(b1_cal_im,'gaussian');
            elseif strcmp(app.SmoothDropDown.Value,'Convolution')
                b1_cal_im_smooth=smooth3(b1_cal_im,'box',3);
            end
            
            b1_cal_thr_im=b1_cal_im_smooth;
            app.mod3Dno_seg=b1_cal_im_smooth;
            b1_cal_thr_im(~app.mskbone3D & ~app.mskphantom3D)=0;
            app.mod3D=b1_cal_thr_im;
            
        end
        
        % B1 correction: Create b1 matrix by inverting reference image.
        function b1correction(app)
            
            b1im=app.b13D;
            % Get binary mask from B1 image in order to suppress noise
            b1mask=bwareaopen(imbinarize(imadjustn(b1im),"global"),10);
            b1mask=imfill( b1mask , 'holes');
            % Inverse b1 image
            app.b1matr=1./double(b1im);
            % Threshold to mask
            app.b1matr(~b1mask)=0;
            % Normalize b1 Matrix
            app.b1matr=double(double(app.b1matr)./max(app.b1matr(:)));
            app.b1matr= imgaussfilt3(app.b1matr,1); %smoothing for pretty images
            app.b1matr=app.b1matr./mean(app.b1matr(b1mask)); %changed b1 matrix to average 1. 
            app.b1matr(~b1mask)=1;%mean(app.b1matr(app.b1matr>0));
            
            if strcmp(app.FlipB1DropDown.Value,'None')
                app.b1matr=app.b1matr;
            elseif strcmp(app.FlipB1DropDown.Value,'X')
                app.b1matr=flip(app.b1matr,1);
            elseif strcmp(app.FlipB1DropDown.Value,'Y')
                app.b1matr=flip(app.b1matr,2);
            elseif strcmp(app.FlipB1DropDown.Value,'Z')
                app.b1matr=flip(app.b1matr,3);
            end
            
            if strcmp(app.ndflipDropDown.Value,'None')
                app.b1matr=app.b1matr;
            elseif strcmp(app.ndflipDropDown.Value,'X')
                app.b1matr=flip(app.b1matr,1);
            elseif strcmp(app.ndflipDropDown.Value,'Y')
                app.b1matr=flip(app.b1matr,2);
            elseif strcmp(app.ndflipDropDown.Value,'Z')
                app.b1matr=flip(app.b1matr,3);
            end
            
            
            disp(mean(app.b1matr(:)))
            disp(mean(app.b1matr(~b1mask)))
        end
        
        % Function to calculate output results
        function res = calculateResults(app)
            
            % Decide on B1 Correction Option
            if app.on_b1.Value
                b1loc=app.b1matr;
            else
                b1loc=ones(size(app.b1matr));
            end
            b1_im=double(app.raw3D).*double(b1loc);
            
            % Local correction using pellet density (UI) Comparing actual density of
            % pellet in image to reference density
            if app.on_cal.Value
                density_is= double(mean(b1_im(app.mskphantom3D>0)));
            else
                density_is= str2double(app.ThrEditField.Value);
            end
            % Decide to include noise adaption or not
            if app.noise.Value
                noise=double(mean(app.raw3D(~app.mskbone3D & ~app.mskphantom3D)));
            else
                noise=double(0);
                
            end
            
            % Shift intensity according to noise, and then scale linear
            % with reference
            density_aim= str2double(app.DensityEditField.Value);
            corr_fact=density_aim./(density_is-noise);
            thr_b1_cal_im=(b1_im-noise).*corr_fact;
            
            
            % Apply calibration to voxelData
            % Include one version without threshold
            tiss_im=thr_b1_cal_im;
            corr_im=tiss_im;
            corr_im((app.mskbone3D | app.mskphantom3D)==0)=0;
            
            % Determine trab/cort zone according to sliders
            trab_zone=[app.Slider_Start_Trabecular.Value app.Slider_Stop_Trabecular.Value];
            cort_zone=[app.Slider_Start_Cortical.Value app.Slider_Stop_Cortical.Value];
            
            % Create a mask for each ROI weird how it is not aligned in z..
            % has to be x normal though. Bounding Box and Array indexing
            % are different for BB it is [y x z] for Array [x y z].
            trab_mask=zeros(size(app.mskbone3D));
            trab_mask(trab_zone(1):trab_zone(2),:,:)=app.mskbone3D(trab_zone(1):trab_zone(2),:,:);
            cort_mask=zeros(size(app.mskbone3D));
            cort_mask(cort_zone(1):cort_zone(2),:,:)=app.mskbone3D(cort_zone(1):cort_zone(2),:,:);
            
            % growth plate mask is the lowest zone from start of the bone
            % to growth plates
            gp_mask=zeros(size(app.mskbone3D));
            gp_mask(trab_zone(2):app.start_bone,:,:)=app.mskbone3D(trab_zone(2):app.start_bone,:,:);
            
            % This mask includes the whole area within the cortical mask
            % including bone marrow. Algorithm: close mask top and bottom,
            % fill it remove top and bottom.
            cort_tiss_mask=cort_mask;
            cort_tiss_mask(cort_zone(1)-1,:,:)=1;
            cort_tiss_mask(cort_zone(2)+1,:,:)=1;
            cort_tiss_mask=imfill(cort_tiss_mask,'holes');
            cort_tiss_mask(cort_zone(1)-1,:,:)=0;
            cort_tiss_mask(cort_zone(2)+1,:,:)=0;
            
            % This mask includes the whole area within the trabecular mask
            % including bone marrow. Algorithm: close mask top and bottom,
            % fill it remove top and bottom.
            trab_tiss_mask=trab_mask;
            trab_tiss_mask(trab_zone(1)-1,:,:)=1;
            trab_tiss_mask(trab_zone(2)+1,:,:)=1;
            trab_tiss_mask=imfill(trab_tiss_mask,'holes');
            trab_tiss_mask(trab_zone(1)-1,:,:)=0;
            trab_tiss_mask(trab_zone(2)+1,:,:)=0;
            
            real_trab_mask= trab_tiss_mask & ~trab_mask;
            
            % Export data to result table
            res=table();
            res.tissue_vl= sum(~app.msk3D,'all') * prod(app.mri_res); % Tissue Volume
            res.bone_vol= sum(app.mskbone3D,'all')  * prod(app.mri_res); % Bone Volume
            res.cort_vol= sum(cort_mask,'all')  * prod(app.mri_res); % Cortical Bone Volume
            res.trab_vol= sum(trab_mask,'all')  * prod(app.mri_res); % Trabecular Bone Volume
            
            res.bone_dens_av= mean(corr_im(app.mskbone3D>0)); % Mean Bone Denisty
            res.cort_dens_av= mean(corr_im(cort_mask>0)); % Mean Cortical Bone Density
            res.trab_dens_av=mean(corr_im(trab_mask>0)); % Mean Trabecular Bone Density
            res.gpa_dens_av= median(corr_im(gp_mask>0)); % Mean Growth Plate Density
            res.cort_tissue_dens_av= mean(tiss_im(cort_tiss_mask>0));  % Mean Cortical Tissue Density
            res.trab_tissue_dens_av= mean(tiss_im(trab_tiss_mask>0)); % Mean Trabecular Tissue Density
            
            res.std_bone_dens= std(corr_im(app.mskbone3D>0)); % std Bone Denisty
            res.std_cort_dens= std(corr_im(cort_mask>0)); % std Cortical Bone Density
            res.std_trab_dens=std(corr_im(trab_mask>0)); % std Trabecular Bone Density
            res.std_gpa_dens=std(corr_im(gp_mask>0));% std Growth Plate Density
            res.std_cort_tissue_dens= std(tiss_im(cort_tiss_mask>0));   % std Cortical Tissue Density
            res.std_trab_tissue_dens= std(tiss_im(trab_tiss_mask>0));  % std Trabecular Tissue Density
            
            res.phantom_dens=double(mean(b1_im(app.mskphantom3D>0)));
            res.noise_dens=double(mean(app.raw3D(~app.mskbone3D & ~app.mskphantom3D)));
            
        end
        
        % function to estimate trabecular and cortical zone depending on
        % start of bone
        function [trab_zone, cort_zone] = estimateROI(app)
            %             % get MRI data from ROI and calculate boundingbox
            %             mri_box=regionprops(app.mskbone3D,'BoundingBox','PixelIdxList');
            %
            %             % Define Start of bone depending on smallest bounding box
            %             app.start_bone = round(mri_box(1).BoundingBox(2)+mri_box(1).BoundingBox(5)); % long axis is y axis
            
            % Calculate trabecular and Cortical zones in mm
            % mm_dist_prox_growth=(ct_startbone - ct_growthplate ) .* ct_res;
            mm_start_bone_mri= app.start_bone .* app.mri_res - app.mm_distance_growth_plates; % where zones start
            
            % Map them on the MRI image (slices
            trab_zone= round( [mm_start_bone_mri-app.mm_size_trab_region mm_start_bone_mri] ./app.mri_res);
            cort_zone = round( [mm_start_bone_mri-app.mm_size_trab_region-app.mm_size_gap_region-app.mm_size_cort_region ...
                mm_start_bone_mri-app.mm_size_trab_region-app.mm_size_gap_region]./app.mri_res);
        end
        
        % This function plots the Growthplate, Trabecular and Cortical ROI
        % only if not in axial view
        function  plotROI(app,trab_zone,cort_zone)
            try
                % Delete old regions if they still exist
                delete(app.l1);delete(app.l2);delete(app.l3);delete(app.l4);delete(app.l5)
                
                % In axial view regions are not visible
                if app.ViewDropDown.Value ~= "Axial"
                    app.l1=line(app.UI_res,[1,64],[trab_zone(1),trab_zone(1)]);
                    app.l2=line(app.UI_res,[1,64],[trab_zone(2),trab_zone(2)]);
                    app.l3=line(app.UI_res,[1,64],[cort_zone(1),cort_zone(1)]);
                    app.l4=line(app.UI_res,[1,64],[cort_zone(2),cort_zone(2)]);
                    app.l5=line(app.UI_res,[1,64],[app.start_bone,app.start_bone]);
                else
                    disp("select non-axial view");
                end
                
                % Change slider values accordingly
                app.Slider_Start_Trabecular.Value=trab_zone(1);
                app.Slider_Stop_Trabecular.Value=trab_zone(2);
                app.Slider_Start_Cortical.Value=cort_zone(1);
                app.Slider_Stop_Cortical.Value=cort_zone(2);
            catch
                disp('No Image Data')
            end
            
        end
        
        %NUM2CLIP copies a numerical-array to the clipboard
        function arraystring = num2clip(~,array)
            %convert the numerical array to a string array
            %note that num2str pads the output array with space characters to account
            %for differing numbers of digits in each index entry
            arraystring = num2str(array);
            arraystring(:,end+1) = char(10); %add a carrige return to the end of each row
            %reshape the array to a single line
            %note that the reshape function reshape is column based so to reshape by
            %rows one must use the inverse of the matrix
            arraystring = reshape(arraystring',1,prod(size(arraystring))); %reshape the array to a single line
            arraystringshift = [' ',arraystring]; %create a copy of arraystring shifted right by one space character
            arraystring = [arraystring,' ']; %add a space to the end of arraystring to make it the same length as arraystringshift
            %now remove the additional space charaters - keeping a single space
            %charater after each 'numerical' entry
            arraystring = arraystring((double(arraystring)~=32 | double(arraystringshift)~=32) & ~(double(arraystringshift==10) & double(arraystring)==32) );
            arraystring(double(arraystring)==32) = char(9); %convert the space characters to tab characters
            clipboard('copy',arraystring); %copy the result to the clipboard ready for pasting
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_Load_Raw
        function Button_Load_RawButtonPushed(app, event)
            try % Try and catch in case user interrupts ui import
                % Import Data
                app.raw3D = importDICOMdata(app);
                % Calculate Bone/Phantom Mask
                [app.msk3D, app.mskbone3D, app.mskphantom3D] = seperateROI(app,app.raw3D); % ROI
                % Update Images and Plots
                updateResIm(app); % Process Image
                app.ViewDropDownValueChanged(app) % Update plots
            catch
                disp("Data Import Failed")
                figure(app.BoneQuantMRIUIFigure)
            end
            
        end

        % Value changed function: SliceSlider
        function SliceSliderValueChanged(app, event)
            updateSlice(app);
        end

        % Button pushed function: ResegmentButton
        function ResegmentButtonPushed(app, event)
            
            % Visibility operations for GUI
            app.SliceBrowserTab.HandleVisibility='off';
            app.DataTab.HandleVisibility='off';
            app.ROITab.HandleVisibility='off';
            app.ExitButton.Visible=1;
            app.OperationButtonGroup.Visible=1;
            app.ROIButtonGroup.Visible=1;
            app.NewROIButton.Visible=1;
            app.ButtonPrev_2.Visible=1;
            app.ButtonNext_2.Visible=1;
            % Seperate Segmentation figure as uifig does not support ROI
            % processing
            app.fig_seg=figure("Name","Manual Segmentation");
            app.ax = axes('Parent', app.fig_seg);
            imshow(imadjust(app.raw2D),'Parent',app.ax);
            colormap(app.ax,'bone');
            axis(app.ax, 'equal');
            hold(app.ax,'on')
            app.ax.Visible='On';
            
            set(app.fig_seg,'Units','pixels',"Position",[app.BoneQuantMRIUIFigure.Position(1)+app.BoneQuantMRIUIFigure.Position(3)...
                app.BoneQuantMRIUIFigure.Position(2) 1600 1400]);
            
            % Maximizing Axis-size in figure window
            set(app.ax,'units','pixels'); % set the axes units to pixels
            x = get(app.ax,'position'); % get the position of the axes
            set(app.ax,'units','pixels') % set the figure units to pixels
            y = get(app.fig_seg,'position'); % get the figure position
            set(gcf,'position',[y(1) y(2) x(3) x(4)]);% set the position of the figure to the length and width of the axes
            set(app.ax,'units','normalized','position',[0 0 1 1]) ;% set the axes units to pixels
            
            % Delete all ROI and derive new ROI based on 2D slice
            app.pos_roi_bone=[];app.neg_roi_bone=[]; app.pos_roi_phantom=[];app.neg_roi_phantom=[];
            [app.pos_roi_phantom, app.neg_roi_phantom] = mask2interactiveROI(app,app.mskphantom2D,app.ax,['b';'k']);
            [app.pos_roi_bone, app.neg_roi_bone] = mask2interactiveROI(app,app.mskbone2D,app.ax,['g';'r']);
            
        end

        % Button pushed function: NewROIButton
        function NewROIButtonPushed(app, event)
            
            % Choose different colors for different ROI draw tools
            if app.BoneButton.Value
                if app.AddButton.Value
                    tmproi=drawfreehand(app.ax,"Color",'g');
                    app.mskbone2D=tmproi.createMask | app.mskbone2D;
                else
                    tmproi=drawfreehand(app.ax,"Color",'r');
                    app.mskbone2D=~tmproi.createMask & app.mskbone2D;
                end
            else
                if app.AddButton.Value
                    tmproi=drawfreehand(app.ax,"Color",'b');
                    app.mskphantom2D =tmproi.createMask | app.mskphantom2D;
                else
                    tmproi=drawfreehand(app.ax,"Color",'k');
                    app.mskphantom2D=~tmproi.createMask & app.mskphantom2D;
                    
                end
            end
            
            % Push Slice to 3D Bone and 3D Phantom Mask
            app.mskbone3D = sliceto3D(app,app.mskbone3D,app.mskbone2D,...
                int32(app.SliceSlider.Value),app.ViewDropDown.Value);
            app.mskphantom3D = sliceto3D(app,app.mskphantom3D,app.mskphantom2D,...
                int32(app.SliceSlider.Value),app.ViewDropDown.Value);
            
            % delete old ROI
            delete(tmproi);
            deleteroi(app,app.pos_roi_bone);deleteroi(app,app.neg_roi_bone);
            deleteroi(app,app.pos_roi_phantom);deleteroi(app,app.neg_roi_phantom);
            
            % Get new ROI from updated 3D masks
            [app.pos_roi_phantom, app.neg_roi_phantom] = mask2interactiveROI(app,app.mskphantom2D,app.ax,['b';'k']);
            [app.pos_roi_bone, app.neg_roi_bone] = mask2interactiveROI(app,app.mskbone2D,app.ax,['g';'r']);
            updateSlice(app)
            
        end

        % Button pushed function: Button_Load_B1
        function Button_Load_B1ButtonPushed(app, event)
            try % Import Dicom
                app.b13D = importDICOMdata(app);
                % Resize to raw image size
                app.b13D = imresize3(app.b13D,size(app.raw3D));
                % Calculate B1 Correction
                b1correction(app);
                % Update Plots
                app.ViewDropDownValueChanged(app);
            catch
                disp("Data Import Error")
                figure(app.BoneQuantMRIUIFigure)
                
            end
            
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            % Visibility UI Changes
            app.SliceBrowserTab.HandleVisibility='on';
            app.DataTab.HandleVisibility='on';
            app.ROITab.HandleVisibility='on';
            app.ExitButton.Visible=0;
            app.OperationButtonGroup.Visible=0;
            app.ROIButtonGroup.Visible=0;
            app.NewROIButton.Visible=0;
            app.ButtonPrev_2.Visible=0;
            app.ButtonNext_2.Visible=0;
            try % Close Segmentation figure if not closed
                close(app.fig_seg);
            catch
%                 disp('Figure already closed')
            end
            
        end

        % Button pushed function: SingleButton
        function SingleButtonPushed(app, event)
            try
                if app.Slider_Start_Trabecular.Value<=1
                    app.GuessButtonPushed(app);
                end
                
                [res] = calculateResults(app);
                % plot result table as figure
                f=figure("Position",[447 629 940 58],'visible','off');
                t=uitable('Data',res{:,:},'ColumnName',res.Properties.VariableNames,...
                    'RowName',res.Properties.RowNames,'Units', 'Normalized', 'Position',[0, 0, 1, 1]);
                app.UITable.Data=t.Data;
                close(f);
            catch
                disp("Check Image, B1 Data, and CT-Data Info missing");
            end
            
        end

        % Value changed function: ViewDropDown
        function ViewDropDownValueChanged(app, event)
            
            % Find first/last slice in each dimension
            [r,c,v] = ind2sub(size(app.msk3D),find(app.msk3D == 1));
            
            switch app.ViewDropDown.Value
                case 'Saggital'
                    app.SliceSlider.Limits=[max([1 min(v)-2]) min([64 max(v)+2])];
                case 'Coronal'
                    app.SliceSlider.Limits=[max([1 min(c)-2]) min([64 max(c)+2])];
                case 'Axial'
                    app.SliceSlider.Limits=[max([1 min(r)-2]) min([64 max(r)+2])];
                otherwise
            end
            % Reset slice slider
            app.SliceSlider.Value=app.SliceSlider.Limits(1);
            
            % update plots
            app.SliceSliderValueChanged(app);
            
        end

        % Button pushed function: DButton
        function DButtonPushed(app, event)
            if ~isdeployed
                volumeViewer(app.mod3D);
            else
                uialert(app.UIFigure, ...
                    '3D viewer is not supported in the compiled version.', ...
                    'Unavailable Feature');
            end
        end

        % Button pushed function: ButtonNext
        function ButtonNextPushed(app, event)
        % Change slice, limit to max and min limits
            if app.SliceSlider.Value<app.SliceSlider.Limits(2)-1
                app.SliceSlider.Value=app.SliceSlider.Value+1;
                app.SliceSliderValueChanged(app)
            else
                app.SliceSlider.Value=app.SliceSlider.Value;
            end
            
        end

        % Button pushed function: ButtonPrev
        function ButtonPrevPushed(app, event)
            % Change slider limit to max and min limits
            if app.SliceSlider.Value>app.SliceSlider.Limits(1)+1
                app.SliceSlider.Value=app.SliceSlider.Value-1;
                app.SliceSliderValueChanged(app)
            else
                app.SliceSlider.Value=app.SliceSlider.Value;
            end
            
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            % same function as Exit Button
            app.ExitButtonPushed(app);
        end

        % Button pushed function: SaveContButton
        function SaveContButtonPushed(app, event)
            ctrcontour=app.msk3D;
            ctrbone=app.mskbone3D;
            ctrphantom=app.mskphantom3D;
            uisave({'ctrcontour','ctrbone','ctrphantom'},'ctrcontour');
        end

        % Button pushed function: LoadContButton
        function LoadContButtonPushed(app, event)
            try
                [file,path] = uigetfile;
                vars=load([path '/' file],'-mat','ctrcontour','ctrbone','ctrphantom');
                
                app.msk3D=vars.ctrcontour;
                app.mskbone3D=vars.ctrbone;
                app.mskphantom3D=vars.ctrphantom;
                
                % update plots
                app.SliceSliderValueChanged(app);
                app.ExitButtonPushed;
            catch
                disp('Aborted import')
            end
        end

        % Button pushed function: GuessButton
        function GuessButtonPushed(app, event)
            
            [trab_zone, cort_zone] = estimateROI(app);
            plotROI(app,trab_zone,cort_zone);
            
            
        end

        % Value changed function: Slider_Start_Trabecular
        function Slider_Start_TrabecularValueChanged(app, event)
            delete(app.l1);
            app.l1=line(app.UI_res,[1,64],[app.Slider_Start_Trabecular.Value,app.Slider_Start_Trabecular.Value]);
        end

        % Value changing function: Slider_Stop_Trabecular
        function Slider_Stop_TrabecularValueChanging(app, event)
            delete(app.l2);
            app.l2=line(app.UI_res,[1,64],[app.Slider_Stop_Trabecular.Value,app.Slider_Stop_Trabecular.Value]);
        end

        % Value changed function: Slider_Start_Cortical
        function Slider_Start_CorticalValueChanged(app, event)
            delete(app.l3);
            app.l3=line(app.UI_res,[1,64],[app.Slider_Start_Cortical.Value,app.Slider_Start_Cortical.Value]);
            
        end

        % Value changed function: Slider_Stop_Cortical
        function Slider_Stop_CorticalValueChanged(app, event)
            delete(app.l4);
            app.l4=line(app.UI_res,[1,64],[app.Slider_Stop_Cortical.Value,app.Slider_Stop_Cortical.Value]);
            
        end

        % Button pushed function: Button_Next_startTrab
        function Button_Next_startTrabPushed(app, event)
            if app.Slider_Start_Trabecular.Value<app.Slider_Start_Trabecular.Limits(2)-1
                app.Slider_Start_Trabecular.Value=app.Slider_Start_Trabecular.Value+1;
                app.Slider_Start_TrabecularValueChanged(app)
            end
        end

        % Button pushed function: Button_Next_stopTrab
        function Button_Next_stopTrabPushed(app, event)
            if app.Slider_Stop_Trabecular.Value<app.Slider_Stop_Trabecular.Limits(2)-1
                app.Slider_Stop_Trabecular.Value=app.Slider_Stop_Trabecular.Value+1;
                app.Slider_Stop_TrabecularValueChanging(app)
            end
        end

        % Button pushed function: Button_Next_startCort
        function Button_Next_startCortPushed(app, event)
            if app.Slider_Start_Cortical.Value<app.Slider_Start_Cortical.Limits(2)-1
                app.Slider_Start_Cortical.Value=app.Slider_Start_Cortical.Value+1;
                app.Slider_Start_CorticalValueChanged(app)
            end
        end

        % Button pushed function: Button_Next_stopCort
        function Button_Next_stopCortPushed(app, event)
            if app.Slider_Stop_Cortical.Value<app.Slider_Stop_Cortical.Limits(2)-1
                app.Slider_Stop_Cortical.Value=app.Slider_Stop_Cortical.Value+1;
                app.Slider_Stop_CorticalValueChanged(app)
            end
        end

        % Button pushed function: Button_Prev_startTrab
        function Button_Prev_startTrabPushed(app, event)
            if app.Slider_Start_Trabecular.Value<app.Slider_Start_Trabecular.Limits(2)+1
                app.Slider_Start_Trabecular.Value=app.Slider_Start_Trabecular.Value-1;
                app.Slider_Start_TrabecularValueChanged(app)
            end
        end

        % Button pushed function: Button_Prev_stopTrab
        function Button_Prev_stopTrabPushed(app, event)
            if app.Slider_Stop_Trabecular.Value<app.Slider_Stop_Trabecular.Limits(2)+1
                app.Slider_Stop_Trabecular.Value=app.Slider_Stop_Trabecular.Value-1;
                app.Slider_Stop_TrabecularValueChanging(app)
            end
        end

        % Button pushed function: Button_Prev_startCort
        function Button_Prev_startCortPushed(app, event)
            if app.Slider_Start_Cortical.Value<app.Slider_Start_Cortical.Limits(2)+1
                app.Slider_Start_Cortical.Value=app.Slider_Start_Cortical.Value-1;
                app.Slider_Start_CorticalValueChanged(app)
            end
        end

        % Button pushed function: Button_Prev_stopCort
        function Button_Prev_stopCortPushed(app, event)
            if app.Slider_Stop_Cortical.Value<app.Slider_Stop_Cortical.Limits(2)+1
                app.Slider_Stop_Cortical.Value=app.Slider_Stop_Cortical.Value-1;
                app.Slider_Stop_CorticalValueChanged(app)
            end
        end

        % Button pushed function: AutoButton
        function AutoButtonPushed(app, event)
            
            % Get Directory
            try
                selpath = uigetdir;
                folders = dir(selpath);


                % Initialize Result table and loop through folders
                restable=table();
                
                for ii=3:length(folders)
                    if isfolder([folders(ii).folder '/' folders(ii).name])
                        
                        % List all folders
                        listing = dir([folders(ii).folder '/' folders(ii).name '/*']);
                        
                        % Get Dicominfo for random file (25)
                        info = dicominfo([folders(ii).folder '/' folders(ii).name '/' listing(25).name]);
                        
                        
                        % Dialogue message for UI
                        f=uifigure();
                        d = uiprogressdlg(f,'Title','Please Wait',...
                            'Message','Opening the application');
                        pause(.5)
                        d.Value = .33;
                        d.Message = 'Loading your data';
                        
                        % Flip image if orientation is faulty
                        image=squeeze(dicomreadVolume([folders(ii).folder '/' folders(ii).name]));
                        if info.PatientPosition~="HFS"
                            image = flip(image,2);
                        end
                        d.Value = 1;
                        % Switch to figure
                        close(f);
                        figure(app.BoneQuantMRIUIFigure);
                        
                        % Update Raw image from import
                        app.raw3D = image;
                        
                        % Update Bone/Phantom Mask
                        [app.msk3D, app.mskbone3D, app.mskphantom3D] = seperateROI(app,app.raw3D); % ROI
                        updateResIm(app); % Process Image
                        
                        % Update views and go to random slice 30
                        app.ViewDropDown.Value='Saggital';
                        app.ViewDropDownValueChanged
                        app.SliceSlider.Value=mean(app.SliceSlider.Limits)-3;
                        app.SliceSliderValueChanged;
                        
                        % Load Contourfile if in folder
                        contourpath = dir([folders(ii).folder '/' folders(ii).name '/*.mat']);
                        if ~isempty(contourpath)
                            vars=load([folders(ii).folder '/' folders(ii).name '/' contourpath(1).name],'-mat','ctrcontour','ctrbone','ctrphantom');
                            app.msk3D=vars.ctrcontour;
                            app.mskbone3D=vars.ctrbone;
                            app.mskphantom3D=vars.ctrphantom;
                            % update plots
                            app.SliceSliderValueChanged(app);
                            disp('Contourfile Loaded')
                        else
                            disp('No Contourfile available')
                        end
                        
                        
                        % An Excelfile can be used in order to import custom slice
                        % numbers for each file within the folder (has to be the same
                        % length
                        excelpath = dir([folders(ii).folder '/' folders(ii).name '/*.xlsx']);
                        try
                            SliceData = readtable([folders(ii).folder '/' folders(ii).name '/' excelpath(1).name ], "UseExcel", false);
                            if height(SliceData)~=1
                                SliceData=[];
                                disp('Excelfile should be a single data entry');
                            end
                            disp('Slice Data Loaded')
                        catch
                            disp('No Excel Slice Data found')
                            SliceData=[];
                        end
                        
                        
                        % If Slice data provided use Slice data from Xls
                        % file
                        if ~isempty(SliceData)
                            
                            trab_zone=[-SliceData.Tstop(1) -SliceData.Tstart(1)] + app.start_bone;
                            cort_zone=[-SliceData.Cstop(1) -SliceData.Cstart(1)] + app.start_bone;
                            
                            plotROI(app,trab_zone,cort_zone);
                        else
                            % If Slice Data is empty estimate by using guess
                            % function
                            app.GuessButtonPushed;
                        end
                        
                        res = calculateResults(app);
                        
                        % Could technically initialize table better but this
                        % warning is not important
                        restable=[restable; res];


                        if app.DCMCheckBox.Value % Export dicoms in all settings by turning this on
                            n=1;
                            perms = dec2bin(2^n-1:-1:0)-'0';
                            for i=1:length(perms)
                                
                                perm=perms(i,:);
                                app.on_b1.Value=perm(1);
                                app.off_b1.Value=~perm(1);
                                %app.on_cal.Value=perm(2);
                                app.on_cal.Value=0;
                                app.off_cal.Value=1;
                                app.on_enhance.Value=0;
                                app.off_enhance.Value=1;
                                %app.noise.Value=perm(3);
                                app.noise.Value=0;
                                %app.on_seg.Value=perm(4);
                                app.on_seg.Value=0;
                                app.off_seg.Value=1;
                                drawnow 
                                app.updateResIm
                                app.SliceSliderValueChanged
                                
                                %savestring=strjoin([folders(ii).name '_' string(logical(perm(1))) 'B1_' string(logical(perm(2))) 'CAL_' string(perm(3)) 'NOISE_' ...
                                %    string(logical(perm(4))) 'SEG.dcm'],'');
                                savestring=strjoin([folders(ii).name '_' string(logical(perm(1))) '_B1.dcm'],'');
                                
                                %writefile(:,:,1,:)=uint16(app.mod3D.*255);
                                writefile(:,:,1,:)= int16(32766*app.mod3Dno_seg/max(app.mod3Dno_seg(:)));
                                if ~exist(folders(ii).name, 'dir')
                                    mkdir(folders(ii).name)
                                else
                                    disp('Directory Exists')
                                end
                                cd(folders(ii).name)
                                if perm(1)
                                    b1name='b1';
                                else
                                    b1name='no_b1';
                                end
                                mkdir(b1name)
                                cd(b1name)
                                dicomwrite(writefile,savestring);
                                cd('..')
                                cd('..')
                            end                        
                        end
                        
                        if 0 %Turn this on to export all sliced png images 
                            n=6;
                            perms = dec2bin(2^n-1:-1:0)-'0';
                            for i=1:length(perms)
                                
                                perm=perms(i,:);
                                if perm(4)
                                    app.ViewDropDown.Value='Saggital';
                                    app.SliceSlider.Value=mean(app.SliceSlider.Limits)-3;
                                    
                                elseif perm(5)
                                    app.ViewDropDown.Value='Coronal';
                                    app.SliceSlider.Value=mean(app.SliceSlider.Limits)+3;
                                elseif perm(6)
                                    app.ViewDropDown.Value='Axial';
                                    app.SliceSlider.Value=mean(app.SliceSlider.Limits);
                                end
                                
                                app.on_b1.Value=perm(1);
                                app.on_cal.Value=perm(2);
                                app.on_enhance.Value=perm(3);
      
                                app.SliceSliderValueChanged
                                                              
                                savestring=strjoin([folders(ii).name '_' string(logical(perm(1))) 'B1_' string(logical(perm(2))) 'CAL_' ...
                                    string(logical(perm(3))) 'ENH_' app.ViewDropDown.Value '.png'],'');
                                
                                figButtonPushed(app);
                                tfig=gcf;
                                saveas(tfig,savestring)
                                close(tfig);    
                            end
                            
                        end
      
                    end
                end
                
            catch e
                fprintf(1,'The identifier was:\n%s',e.identifier);
                fprintf(1,'There was an error! The message was:\n%s',e.message);
            end
            
            % plot result table as figure
            f=figure("Position",[447 629 940 58],'visible','off');
            t=uitable('Data',restable{:,:},'ColumnName',restable.Properties.VariableNames,...
                'RowName',restable.Properties.RowNames,'Units', 'Normalized', 'Position',[0, 0, 1, 1]);
            app.UITable.Data=t.Data;
            close(f);
        end

        % Value changed function: autoCheckBox
        function autoCheckBoxValueChanged(app, event)
            % Automatically evaluate threshold is triggered
            app.Slider_Threshold.Enable= ~app.autoCheckBox.Value;
            
            adj_im=imadjustn(app.raw3D);
            app.Slider_Threshold.Value=graythresh(adj_im);
            [app.msk3D, app.mskbone3D, app.mskphantom3D] = seperateROI(app,app.raw3D); % ROI
            updateResIm(app); % Process Image
            app.SliceSliderValueChanged(app) % Update plots
        end

        % Value changed function: Slider_Threshold
        function Slider_ThresholdValueChanged(app, event)
            % Deactivate automatical threshold evaluation
            app.autoCheckBox.Value=0;
            [app.msk3D, app.mskbone3D, app.mskphantom3D] = seperateROI(app,app.raw3D); % ROI
            updateResIm(app); % Process Image
            app.SliceSliderValueChanged(app) % Update plots
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            try
                [baseName, folder] = uigetfile('*.xlsx');
                excelpath = fullfile(folder, baseName);
                SliceData = readtable(excelpath, "UseExcel", false);
                
                if height(SliceData)~=1
                    SliceData=[];
                    disp('Excelfile should be a single data entry');
                end
                disp('Slice Data Loaded')
            catch
                disp('No Excel Slice Data found')
                SliceData=[];
            end
            
            if ~isempty(SliceData)
                %                 mri_box=regionprops(app.mskbone3D,'BoundingBox','PixelIdxList');
                %                 app.start_bone = round(mri_box(1).BoundingBox(2)+mri_box(1).BoundingBox(5)); % long axis is y axis
                trab_zone=[-SliceData.Tstop(1) -SliceData.Tstart(1)] + app.start_bone;
                cort_zone=[-SliceData.Cstop(1) -SliceData.Cstart(1)] + app.start_bone;
                plotROI(app,trab_zone,cort_zone);
            end
            disp('Region Loaded');
            figure(app.BoneQuantMRIUIFigure);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            %             mri_box=regionprops(app.mskbone3D,'BoundingBox','PixelIdxList');
            %             app.start_bone = round(mri_box(1).BoundingBox(2)+mri_box(1).BoundingBox(5)); % long axis is y axis
            
            filename = 'ASliceData.xlsx';
            Tstop=  app.start_bone - app.Slider_Start_Trabecular.Value  ;
            Tstart=  app.start_bone - app.Slider_Stop_Trabecular.Value  ;
            Cstop=  app.start_bone - app.Slider_Start_Cortical.Value  ;
            Cstart=  app.start_bone - app.Slider_Stop_Cortical.Value  ;
            T=table(Tstart,Tstop,Cstart,Cstop);
            
            path=uigetdir();
            try
                writetable(T,[path '/' filename]);
                disp('Region Saved');
            catch
                warning('Region could not be saved, open in other program')
            end
            figure(app.BoneQuantMRIUIFigure);
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            try
                path=uigetdir();


                % Decide on B1 Correction Option
                if app.B1CorrectedCheckBox.Value
                    b1loc=app.b1matr;
                else
                    b1loc=ones(size(app.b1matr));
                end
                
                b1_im=double(app.raw3D).*double(b1loc);
                
                % Decide on Calibration Option
                if app.PhantomCaliCheckBox.Value
                    density_is= double(mean(b1_im(app.mskphantom3D>0)));
                else
                    density_is= str2double(app.ThrEditField.Value);
                end
                
                if app.NoiseCaliCheckBox.Value
                    noise=double(mean(app.raw3D(~app.mskbone3D & ~app.mskphantom3D)));
                else
                    noise=double(0);
                    
                end
                
                if ~app.NoiseCaliCheckBox.Value && ~app.PhantomCaliCheckBox.Value
                    corr_fact=1;
                else
                    density_aim= str2double(app.DensityEditField.Value);
                    corr_fact=density_aim./(density_is-noise);
                end

                
                b1_cal_im=(b1_im-noise).*corr_fact;
                b1_cal_im(b1_cal_im<=0)=0;
                
                % Decide on Thresholding option
                if app.ContouredCheckBox.Value
                    mask= app.mskbone3D | app.mskphantom3D;
                else
                    mask=ones(size(app.msk3D));
                end
                
                b1_cal_im_thr=b1_cal_im;
                b1_cal_im_thr(mask==0)=0;


                % write file as integer scaled to 255 grayscale
                %writefile(:,:,1,:)=uint16(b1_cal_im_thr.*255);
                %this is where it is tricky as imageJ and AMIDe will always
                %interpret int16 images
                writefile(:,:,1,:)= int16(32766*b1_cal_im_thr./max(b1_cal_im_thr(:)));
                dicomwrite(writefile,fullfile(path, 'MRIm.dcm'))
            catch
                disp('Aborted Export')
            end
            
        end

        % Button pushed function: ButtonNext_2
        function ButtonNext_2Pushed(app, event)
            app.ExitButtonPushed;
            app.ButtonNextPushed;
            app.ResegmentButtonPushed;
            
        end

        % Button pushed function: ButtonPrev_2
        function ButtonPrev_2Pushed(app, event)
            app.ExitButtonPushed;
            app.ButtonPrevPushed;
            app.ResegmentButtonPushed;
        end

        % Selection changed function: CalibrPhantomButtonGroup
        function CalibrPhantomButtonGroupSelectionChanged(app, event)
        updateResIm(app);
        updateSlice(app);
        end

        % Selection changed function: B1CorrectionButtonGroup
        function B1CorrectionButtonGroupSelectionChanged(app, event)
            updateResIm(app);
            updateSlice(app);
        end

        % Selection changed function: EnhanceDisplayButtonGroup
        function EnhanceDisplayButtonGroupSelectionChanged(app, event)
            updateResIm(app);
            updateSlice(app);
        end

        % Value changed function: CmapDropDown
        function CmapDropDownValueChanged(app, event)
            updateResIm(app);
            updateSlice(app);
        end

        % Value changed function: noise
        function noiseValueChanged(app, event)
            updateResIm(app);
            updateSlice(app);
        end

        % Value changed function: ThrEditField
        function ThrEditFieldValueChanged(app, event)
            updateResIm(app);
            updateSlice(app);
        end

        % Value changed function: DensityEditField
        function DensityEditFieldValueChanged(app, event)
            updateResIm(app);
            updateSlice(app);
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            num2clip(app,[app.UITable.Data]);

        end

        % Selection changed function: ExportDICOMButtonGroup
        function ExportDICOMButtonGroupSelectionChanged(app, event)
            selectedButton = app.ExportDICOMButtonGroup.SelectedObject;
            
        end

        % Callback function
        function DScreenshotButtonPushed(app, event)
            % Decide on B1 Correction Option
            if app.shot_B1CorrectedCheckBox.Value
                b1loc=app.b1matr;
            else
                b1loc=ones(size(app.b1matr));
            end
            
            b1_im=double(app.raw3D).*double(b1loc);
            
            % Decide on Calibration Option
            if app.shot_PhantomCaliCheckBox.Value
                density_is= double(mean(b1_im(app.mskphantom3D>0)));
            else
                density_is= str2double(app.ThrEditField.Value);
            end
            
            if app.shot_NoiseCaliCheckBox.Value
                noise=double(mean(app.raw3D(~app.mskbone3D & ~app.mskphantom3D)));
            else
                noise=double(0);
                
            end
            density_aim= str2double(app.DensityEditField.Value);
            corr_fact=density_aim./(density_is-noise);
            
            b1_cal_im=(b1_im-noise).*corr_fact;
            b1_cal_im(b1_cal_im<=0)=0;
            
            % Decide on Thresholding option
            if app.shot_ContouredCheckBox.Value
                mask= app.mskbone3D | app.mskphantom3D;
            else
                mask=ones(size(app.msk3D));
            end
            
            b1_cal_im_thr=b1_cal_im;
            b1_cal_im_thr(mask==0)=0;
            
            
            CurSlice= int32(app.SliceSlider.Value);
            switch app.ViewDropDown.Value
                case 'Saggital'
                    dispim =     squeeze(b1_cal_im_thr(:,:,CurSlice));
                case 'Coronal'
                    dispim =     squeeze(b1_cal_im_thr(:,CurSlice,:));
                case 'Axial'
                    dispim = squeeze(b1_cal_im_thr(CurSlice,:,:));
                otherwise
            end
            
            figure()
            imshow((dispim))
            colormap(app.CmapDropDown.Value)
            
            
        end

        % Selection changed function: SegmentationButtonGroup
        function SegmentationButtonGroupSelectionChanged(app, event)
            updateResIm(app);
            updateSlice(app);
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            updateResIm(app);
            updateSlice(app);
        end

        % Button pushed function: figButton
        function figButtonPushed(app, event)
            % Create a temporary figure with axes.
            fig = figure;
            figAxes = axes(fig);
            % Copy all UIAxes children, take over axes limits and aspect ratio.
            allChildren = app.UI_res.XAxis.Parent.Children;
            copyobj(allChildren, figAxes)
            figAxes.XLim = app.UI_res.XLim;
            figAxes.YLim = app.UI_res.YLim;
            figAxes.ZLim = app.UI_res.ZLim;
            figAxes.DataAspectRatio = app.UI_res.DataAspectRatio;
            colormap(app.CmapDropDown.Value)
            
            if app.cmaplim.Value==0
                caxis(figAxes,'auto')
                cl = caxis(figAxes);
                app.cmapmin.Value=mat2str(cl(1));
                app.cmapmax.Value=mat2str(cl(2));
            else
                caxis(figAxes,'manual');
                cl(1)=str2double(app.cmapmin.Value);
                cl(2)=str2double(app.cmapmax.Value);
                caxis(figAxes,cl)
            end
            set(gca, 'visible', 'off')

            
            prompt = {'Enter filename'};
            dlgtitle = 'Export';
            definput = {'exportMRI'};
            opts.Interpreter = 'tex';
            answer = inputdlg(prompt,dlgtitle,[1 40],definput,opts);
            disp([answer{1}, '_slice_', mat2str(round(app.SliceSlider.Value)) ,'_', ...
                app.ViewDropDown.Value, '_b1_', mat2str(app.on_b1.Value)])
            
            print(fig,[answer{1}, '_slice_', mat2str(round(app.SliceSlider.Value)) ,'_', ...
                app.ViewDropDown.Value, '_b1_', mat2str(app.on_b1.Value)],...
                '-dpng','-r1200')
            close(fig)
            
        end

        % Value changed function: cmaplim
        function cmaplimValueChanged(app, event)
            value = app.cmaplim.Value;
            updateSlice(app)
        end

        % Value changed function: cmapmin
        function cmapminValueChanged(app, event)
            value = app.cmapmin.Value;
            updateSlice(app)
        end

        % Value changed function: cmapmax
        function cmapmaxValueChanged(app, event)
            value = app.cmapmax.Value;
            updateSlice(app)
        end

        % Value changed function: MRIderiveddensityCheckBox
        function MRIderiveddensityCheckBoxValueChanged(app, event)
            value = app.MRIderiveddensityCheckBox.Value;
            updateSlice(app)
        end

        % Value changed function: SmoothDropDown
        function SmoothDropDownValueChanged(app, event)
            value = app.SmoothDropDown.Value;
            updateSlice(app)
        end

        % Value changed function: FlipB1DropDown
        function FlipB1DropDownValueChanged(app, event)
            value = app.FlipB1DropDown.Value;
            b1correction(app)
            updateSlice(app)
        end

        % Value changed function: ndflipDropDown
        function ndflipDropDownValueChanged(app, event)
            value = app.ndflipDropDown.Value;
            b1correction(app)
            updateSlice(app)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BoneQuantMRIUIFigure = uifigure('Visible', 'off');
            app.BoneQuantMRIUIFigure.Name = 'BoneQuantMRI';
            app.UI_raw = uiaxes(app.BoneQuantMRIUIFigure);
            title(app.UI_raw, 'Raw Image')
            xlabel(app.UI_raw, 'X')
            ylabel(app.UI_raw, 'Y')
            app.UI_b1 = uiaxes(app.BoneQuantMRIUIFigure);
            title(app.UI_b1, 'B1 Correction Image')
            xlabel(app.UI_b1, 'X')
            ylabel(app.UI_b1, 'Y')
            app.UI_res = uiaxes(app.BoneQuantMRIUIFigure);
            title(app.UI_res, 'Resulting Image')
            xlabel(app.UI_res, 'X')
            ylabel(app.UI_res, 'Y')
            app.MRIAnalysisResultsLabel = uilabel(app.BoneQuantMRIUIFigure);
            app.MRIAnalysisResultsLabel.Text = 'MRI Analysis Results ';
            app.TabGroup = uitabgroup(app.BoneQuantMRIUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.Button_Load_Raw = uibutton(app.DataTab, 'push');
            app.Button_Load_Raw.ButtonPushedFcn = createCallbackFcn(app, @Button_Load_RawButtonPushed, true);
            app.Button_Load_Raw.Text = 'Select DICOM';
            app.Button_Load_B1 = uibutton(app.DataTab, 'push');
            app.Button_Load_B1.ButtonPushedFcn = createCallbackFcn(app, @Button_Load_B1ButtonPushed, true);
            app.Button_Load_B1.Text = 'Select DICOM';
            app.SelectRawImageLabel = uilabel(app.DataTab);
            app.SelectRawImageLabel.Text = 'Select Raw Image';
            app.SelectB1CorrectionLabel = uilabel(app.DataTab);
            app.SelectB1CorrectionLabel.Text = 'Select B1 Correction';
            app.Step1SelectMRIImageLabel = uilabel(app.DataTab);
            app.Step1SelectMRIImageLabel.Text = 'Step 1: Select MRI Image';
            app.Step2SelectB1CorrectionImageifrequiredLabel = uilabel(app.DataTab);
            app.Step2SelectB1CorrectionImageifrequiredLabel.Text = {'Step 2: Select B1 Correction Image '; 'if required.'};
            app.SliceBrowserTab = uitab(app.TabGroup);
            app.SliceBrowserTab.Title = 'Slice Browser';
            app.ButtonNext = uibutton(app.SliceBrowserTab, 'push');
            app.ButtonNext.ButtonPushedFcn = createCallbackFcn(app, @ButtonNextPushed, true);
            app.ButtonNext.Text = '>>';
            app.ButtonPrev = uibutton(app.SliceBrowserTab, 'push');
            app.ButtonPrev.ButtonPushedFcn = createCallbackFcn(app, @ButtonPrevPushed, true);
            app.ButtonPrev.Text = '<<';
            app.ViewDropDown = uidropdown(app.SliceBrowserTab);
            app.ViewDropDown.Items = {'Saggital', 'Coronal', 'Axial'};
            app.ViewDropDown.ValueChangedFcn = createCallbackFcn(app, @ViewDropDownValueChanged, true);
            app.ViewDropDown.Value = 'Saggital';
            app.DButton = uibutton(app.SliceBrowserTab, 'push');
            app.DButton.ButtonPushedFcn = createCallbackFcn(app, @DButtonPushed, true);
            app.DButton.Text = '3D';
            app.SliceSliderLabel = uilabel(app.SliceBrowserTab);
            app.SliceSliderLabel.Text = 'Slice';
            app.SliceSlider = uislider(app.SliceBrowserTab);
            app.SliceSlider.Limits = [1 64];
            app.SliceSlider.ValueChangedFcn = createCallbackFcn(app, @SliceSliderValueChanged, true);
            app.SliceSlider.Value = 1;
            app.Step3UseSliceBrowsertoNavigatethrough3DMRIimageLabel = uilabel(app.SliceBrowserTab);
            app.Step3UseSliceBrowsertoNavigatethrough3DMRIimageLabel.Text = {'Step 3: Use Slice Browser to '; 'Navigate through 3D MRI image'};
            app.Step4Usethe3DImagebuttontocheckhowtheImagelooksin3DLabel = uilabel(app.SliceBrowserTab);
            app.Step4Usethe3DImagebuttontocheckhowtheImagelooksin3DLabel.Text = {'Step 4: Use the 3D Image button '; 'to check how the Image looks in 3D'};
            app.SegentationTab = uitab(app.TabGroup);
            app.SegentationTab.Title = 'Segentation';
            app.ResegmentButton = uibutton(app.SegentationTab, 'push');
            app.ResegmentButton.ButtonPushedFcn = createCallbackFcn(app, @ResegmentButtonPushed, true);
            app.ResegmentButton.Text = 'Resegment';
            app.SaveContButton = uibutton(app.SegentationTab, 'push');
            app.SaveContButton.ButtonPushedFcn = createCallbackFcn(app, @SaveContButtonPushed, true);
            app.SaveContButton.Text = 'Save Cont';
            app.LoadContButton = uibutton(app.SegentationTab, 'push');
            app.LoadContButton.ButtonPushedFcn = createCallbackFcn(app, @LoadContButtonPushed, true);
            app.LoadContButton.Text = 'Load Cont';
            app.Slider_Threshold = uislider(app.SegentationTab);
            app.Slider_Threshold.Limits = [0 1];
            app.Slider_Threshold.ValueChangedFcn = createCallbackFcn(app, @Slider_ThresholdValueChanged, true);
            app.Slider_Threshold.Enable = 'off';
            app.autoCheckBox = uicheckbox(app.SegentationTab);
            app.autoCheckBox.ValueChangedFcn = createCallbackFcn(app, @autoCheckBoxValueChanged, true);
            app.autoCheckBox.Text = 'auto';
            app.autoCheckBox.Value = true;
            app.ThresholdLabel = uilabel(app.SegentationTab);
            app.ThresholdLabel.Text = 'Threshold';
            app.OperationButtonGroup = uibuttongroup(app.SegentationTab);
            app.OperationButtonGroup.Title = 'Operation';
            app.AddButton = uiradiobutton(app.OperationButtonGroup);
            app.AddButton.Text = 'Add';
            app.AddButton.Value = true;
            app.RemoveButton = uiradiobutton(app.OperationButtonGroup);
            app.RemoveButton.Text = 'Remove';
            app.ROIButtonGroup = uibuttongroup(app.SegentationTab);
            app.ROIButtonGroup.Title = 'ROI';
            app.BoneButton = uiradiobutton(app.ROIButtonGroup);
            app.BoneButton.Text = 'Bone';
            app.BoneButton.Value = true;
            app.PhantomButton = uiradiobutton(app.ROIButtonGroup);
            app.PhantomButton.Text = 'Phantom';
            app.NewROIButton = uibutton(app.SegentationTab, 'push');
            app.NewROIButton.ButtonPushedFcn = createCallbackFcn(app, @NewROIButtonPushed, true);
            app.NewROIButton.Text = 'New ROI';
            app.ExitButton = uibutton(app.SegentationTab, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.ButtonPrev_2 = uibutton(app.SegentationTab, 'push');
            app.ButtonPrev_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonPrev_2Pushed, true);
            app.ButtonPrev_2.Text = '<<';
            app.ButtonNext_2 = uibutton(app.SegentationTab, 'push');
            app.ButtonNext_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonNext_2Pushed, true);
            app.ButtonNext_2.Text = '>>';
            app.Step3UseSliceBrowsertoNavigatethrough3DMRIimageLabel_2 = uilabel(app.SegentationTab);
            app.Step3UseSliceBrowsertoNavigatethrough3DMRIimageLabel_2.Text = {'Step 4: Adapt Threshold. See 2 ROI: '; '(Blue, Phantom) (Orange, Bone). '; 'If only one is displayed increase '; 'Threshold. Be careful, '; 'changing threshold overwrites '; 'manual Segmentation'};
            app.Step4Usethe3DImagebuttontocheckhowtheImagelooksin3DLabel_2 = uilabel(app.SegentationTab);
            app.Step4Usethe3DImagebuttontocheckhowtheImagelooksin3DLabel_2.Text = {'Step 5: Use Resegment Button to '; 'manually change ROI. Select '; '''New ROI'' and Add/Remove '; 'Bone/Phantom'};
            app.Step4Usethe3DImagebuttontocheckhowtheImagelooksin3DLabel_3 = uilabel(app.SegentationTab);
            app.Step4Usethe3DImagebuttontocheckhowtheImagelooksin3DLabel_3.Text = {'Step 6: Save Contour within '; 'the Dicom folder of each image. '};
            app.ROITab = uitab(app.TabGroup);
            app.ROITab.Title = 'ROI';
            app.Slider_Start_Trabecular = uislider(app.ROITab);
            app.Slider_Start_Trabecular.Limits = [0 64];
            app.Slider_Start_Trabecular.ValueChangedFcn = createCallbackFcn(app, @Slider_Start_TrabecularValueChanged, true);
            app.Slider_Stop_Trabecular = uislider(app.ROITab);
            app.Slider_Stop_Trabecular.Limits = [0 64];
            app.Slider_Stop_Trabecular.ValueChangingFcn = createCallbackFcn(app, @Slider_Stop_TrabecularValueChanging, true);
            app.Slider_Start_Cortical = uislider(app.ROITab);
            app.Slider_Start_Cortical.Limits = [0 64];
            app.Slider_Start_Cortical.ValueChangedFcn = createCallbackFcn(app, @Slider_Start_CorticalValueChanged, true);
            app.Slider_Stop_Cortical = uislider(app.ROITab);
            app.Slider_Stop_Cortical.Limits = [0 64];
            app.Slider_Stop_Cortical.ValueChangedFcn = createCallbackFcn(app, @Slider_Stop_CorticalValueChanged, true);
            app.TrabecularLabel = uilabel(app.ROITab);
            app.TrabecularLabel.Text = 'Trabecular';
            app.CorticalLabel = uilabel(app.ROITab);
            app.CorticalLabel.Text = 'Cortical';
            app.GuessButton = uibutton(app.ROITab, 'push');
            app.GuessButton.ButtonPushedFcn = createCallbackFcn(app, @GuessButtonPushed, true);
            app.GuessButton.Text = 'Guess';
            app.Button_Next_startCort = uibutton(app.ROITab, 'push');
            app.Button_Next_startCort.ButtonPushedFcn = createCallbackFcn(app, @Button_Next_startCortPushed, true);
            app.Button_Next_startCort.Text = '>>';
            app.Button_Next_stopCort = uibutton(app.ROITab, 'push');
            app.Button_Next_stopCort.ButtonPushedFcn = createCallbackFcn(app, @Button_Next_stopCortPushed, true);
            app.Button_Next_stopCort.Text = '>>';
            app.Button_Next_startTrab = uibutton(app.ROITab, 'push');
            app.Button_Next_startTrab.ButtonPushedFcn = createCallbackFcn(app, @Button_Next_startTrabPushed, true);
            app.Button_Next_startTrab.Text = '>>';
            app.Button_Next_stopTrab = uibutton(app.ROITab, 'push');
            app.Button_Next_stopTrab.ButtonPushedFcn = createCallbackFcn(app, @Button_Next_stopTrabPushed, true);
            app.Button_Next_stopTrab.Text = '>>';
            app.Button_Prev_startTrab = uibutton(app.ROITab, 'push');
            app.Button_Prev_startTrab.ButtonPushedFcn = createCallbackFcn(app, @Button_Prev_startTrabPushed, true);
            app.Button_Prev_startTrab.Text = '<<';
            app.Button_Prev_stopTrab = uibutton(app.ROITab, 'push');
            app.Button_Prev_stopTrab.ButtonPushedFcn = createCallbackFcn(app, @Button_Prev_stopTrabPushed, true);
            app.Button_Prev_stopTrab.Text = '<<';
            app.Button_Prev_stopCort = uibutton(app.ROITab, 'push');
            app.Button_Prev_stopCort.ButtonPushedFcn = createCallbackFcn(app, @Button_Prev_stopCortPushed, true);
            app.Button_Prev_stopCort.Text = '<<';
            app.Button_Prev_startCort = uibutton(app.ROITab, 'push');
            app.Button_Prev_startCort.ButtonPushedFcn = createCallbackFcn(app, @Button_Prev_startCortPushed, true);
            app.Button_Prev_startCort.Text = '<<';
            app.LoadButton = uibutton(app.ROITab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveButton = uibutton(app.ROITab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ROILabel = uilabel(app.ROITab);
            app.ROILabel.Text = 'ROI:';
            app.Step3UseSliceBrowsertoNavigatethrough3DMRIimageLabel_3 = uilabel(app.ROITab);
            app.Step3UseSliceBrowsertoNavigatethrough3DMRIimageLabel_3.Text = {'Step 7: Chose Trabecular and '; 'Cortical region. Can be loaded '; 'from xls File within the Image Folder '};
            app.Step4Usethe3DImagebuttontocheckhowtheImagelooksin3DLabel_4 = uilabel(app.ROITab);
            app.Step4Usethe3DImagebuttontocheckhowtheImagelooksin3DLabel_4.Text = {'Note: The Beginning of bone cannot '; 'be adapted and can only be changed'; 'by adapting the threshold (Step 4)'};
            app.Step4Usethe3DImagebuttontocheckhowtheImagelooksin3DLabel_5 = uilabel(app.ROITab);
            app.Step4Usethe3DImagebuttontocheckhowtheImagelooksin3DLabel_5.Text = {'Note: Guess Button provides an '; 'estimate of zones. '};
            app.Step4Usethe3DImagebuttontocheckhowtheImagelooksin3DLabel_6 = uilabel(app.ROITab);
            app.Step4Usethe3DImagebuttontocheckhowtheImagelooksin3DLabel_6.Text = {'Step 8: Save ROI within the Image '; 'Folder. '};
            app.DataProcessingTab = uitab(app.TabGroup);
            app.DataProcessingTab.Title = 'Data Processing';
            app.SingleButton = uibutton(app.DataProcessingTab, 'push');
            app.SingleButton.ButtonPushedFcn = createCallbackFcn(app, @SingleButtonPushed, true);
            app.SingleButton.Text = 'Single';
            app.AutoButton = uibutton(app.DataProcessingTab, 'push');
            app.AutoButton.ButtonPushedFcn = createCallbackFcn(app, @AutoButtonPushed, true);
            app.AutoButton.Text = 'Auto';
            app.DensityEditFieldLabel = uilabel(app.DataProcessingTab);
            app.DensityEditFieldLabel.Text = 'Density';
            app.DensityEditField = uieditfield(app.DataProcessingTab, 'text');
            app.DensityEditField.ValueChangedFcn = createCallbackFcn(app, @DensityEditFieldValueChanged, true);
            app.DensityEditField.Value = '0.71';
            app.ThrEditFieldLabel = uilabel(app.DataProcessingTab);
            app.ThrEditFieldLabel.Text = 'Thr';
            app.ThrEditField = uieditfield(app.DataProcessingTab, 'text');
            app.ThrEditField.ValueChangedFcn = createCallbackFcn(app, @ThrEditFieldValueChanged, true);
            app.ThrEditField.Value = '4265.8';
            app.CalibrPhantomButtonGroup = uibuttongroup(app.DataProcessingTab);
            app.CalibrPhantomButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CalibrPhantomButtonGroupSelectionChanged, true);
            app.CalibrPhantomButtonGroup.Title = 'Calibr. Phantom';
            app.on_cal = uiradiobutton(app.CalibrPhantomButtonGroup);
            app.on_cal.Text = 'on';
            app.on_cal.Value = true;
            app.off_cal = uiradiobutton(app.CalibrPhantomButtonGroup);
            app.off_cal.Text = 'off';
            app.noise = uicheckbox(app.CalibrPhantomButtonGroup);
            app.noise.ValueChangedFcn = createCallbackFcn(app, @noiseValueChanged, true);
            app.noise.Text = 'Noise';
            app.noise.Value = true;
            app.B1CorrectionButtonGroup = uibuttongroup(app.DataProcessingTab);
            app.B1CorrectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @B1CorrectionButtonGroupSelectionChanged, true);
            app.B1CorrectionButtonGroup.Title = 'B1 Correction ';
            app.on_b1 = uiradiobutton(app.B1CorrectionButtonGroup);
            app.on_b1.Text = 'on';
            app.on_b1.Value = true;
            app.off_b1 = uiradiobutton(app.B1CorrectionButtonGroup);
            app.off_b1.Text = 'off';
            app.ExportButton = uibutton(app.DataProcessingTab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export ';
            app.ExportDICOMButtonGroup = uibuttongroup(app.DataProcessingTab);
            app.ExportDICOMButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ExportDICOMButtonGroupSelectionChanged, true);
            app.ExportDICOMButtonGroup.Title = 'Export DICOM';
            app.ContouredCheckBox = uicheckbox(app.ExportDICOMButtonGroup);
            app.ContouredCheckBox.Text = 'Contoured';
            app.B1CorrectedCheckBox = uicheckbox(app.ExportDICOMButtonGroup);
            app.B1CorrectedCheckBox.Text = 'B1 Corrected';
            app.PhantomCaliCheckBox = uicheckbox(app.ExportDICOMButtonGroup);
            app.PhantomCaliCheckBox.Text = 'Phantom Cali';
            app.NoiseCaliCheckBox = uicheckbox(app.ExportDICOMButtonGroup);
            app.NoiseCaliCheckBox.Text = 'Noise Cali';
            app.EnhanceDisplayButtonGroup = uibuttongroup(app.DataProcessingTab);
            app.EnhanceDisplayButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EnhanceDisplayButtonGroupSelectionChanged, true);
            app.EnhanceDisplayButtonGroup.Title = 'Enhance Display';
            app.on_enhance = uiradiobutton(app.EnhanceDisplayButtonGroup);
            app.on_enhance.Text = 'on';
            app.off_enhance = uiradiobutton(app.EnhanceDisplayButtonGroup);
            app.off_enhance.Text = 'off';
            app.off_enhance.Value = true;
            app.CmapDropDownLabel = uilabel(app.DataProcessingTab);
            app.CmapDropDownLabel.Text = 'Cmap';
            app.CmapDropDown = uidropdown(app.DataProcessingTab);
            app.CmapDropDown.Items = {'Jet', 'Gray', 'Bone', 'Hot', 'Lines'};
            app.CmapDropDown.ValueChangedFcn = createCallbackFcn(app, @CmapDropDownValueChanged, true);
            app.CmapDropDown.Value = 'Jet';
            app.Step3UseSliceBrowsertoNavigatethrough3DMRIimageLabel_4 = uilabel(app.DataProcessingTab);
            app.Step3UseSliceBrowsertoNavigatethrough3DMRIimageLabel_4.Text = {'Step 9: Decide on Calibration Method'; 'Global (Calibration Phantom) and '; 'Local (B1 Correction) '};
            app.Step4Usethe3DImagebuttontocheckhowtheImagelooksin3DLabel_8 = uilabel(app.DataProcessingTab);
            app.Step4Usethe3DImagebuttontocheckhowtheImagelooksin3DLabel_8.Text = {'Step 10: Get Results by Pressing '; 'Single'};
            app.SegmentationButtonGroup = uibuttongroup(app.DataProcessingTab);
            app.SegmentationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SegmentationButtonGroupSelectionChanged, true);
            app.SegmentationButtonGroup.Title = 'Segmentation';
            app.on_seg = uiradiobutton(app.SegmentationButtonGroup);
            app.on_seg.Text = 'on';
            app.on_seg.Value = true;
            app.off_seg = uiradiobutton(app.SegmentationButtonGroup);
            app.off_seg.Text = 'off';
            app.CheckBox = uicheckbox(app.DataProcessingTab);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.CheckBox.Value = true;
            app.figButton = uibutton(app.DataProcessingTab, 'push');
            app.figButton.ButtonPushedFcn = createCallbackFcn(app, @figButtonPushed, true);
            app.figButton.Text = '*.fig';
            app.cmapmin = uieditfield(app.DataProcessingTab, 'text');
            app.cmapmin.ValueChangedFcn = createCallbackFcn(app, @cmapminValueChanged, true);
            app.cmapmax = uieditfield(app.DataProcessingTab, 'text');
            app.cmapmax.ValueChangedFcn = createCallbackFcn(app, @cmapmaxValueChanged, true);
            app.cmaplim = uicheckbox(app.DataProcessingTab);
            app.cmaplim.ValueChangedFcn = createCallbackFcn(app, @cmaplimValueChanged, true);
            app.cmaplim.Text = 'Cmap Lim';
            app.MRIderiveddensityCheckBox = uicheckbox(app.DataProcessingTab);
            app.MRIderiveddensityCheckBox.ValueChangedFcn = createCallbackFcn(app, @MRIderiveddensityCheckBoxValueChanged, true);
            app.MRIderiveddensityCheckBox.Text = 'MRI derived density';
            app.DCMCheckBox = uicheckbox(app.DataProcessingTab);
            app.DCMCheckBox.Text = 'DCM';
            app.SmoothDropDownLabel = uilabel(app.DataProcessingTab);
            app.SmoothDropDownLabel.Text = 'Smooth';
            app.SmoothDropDown = uidropdown(app.DataProcessingTab);
            app.SmoothDropDown.Items = {'None', 'Gauss', 'Convolution'};
            app.SmoothDropDown.ValueChangedFcn = createCallbackFcn(app, @SmoothDropDownValueChanged, true);
            app.SmoothDropDown.Value = 'None';
            app.FlipB1DropDownLabel = uilabel(app.DataProcessingTab);
            app.FlipB1DropDownLabel.Text = 'Flip B1';
            app.FlipB1DropDown = uidropdown(app.DataProcessingTab);
            app.FlipB1DropDown.Items = {'None', 'X', 'Y', 'Z'};
            app.FlipB1DropDown.ValueChangedFcn = createCallbackFcn(app, @FlipB1DropDownValueChanged, true);
            app.FlipB1DropDown.Value = 'X';
            app.ndflipDropDownLabel = uilabel(app.DataProcessingTab);
            app.ndflipDropDownLabel.Text = '2nd flip';
            app.ndflipDropDown = uidropdown(app.DataProcessingTab);
            app.ndflipDropDown.Items = {'None', 'X', 'Y', 'Z'};
            app.ndflipDropDown.ValueChangedFcn = createCallbackFcn(app, @ndflipDropDownValueChanged, true);
            app.ndflipDropDown.Value = 'None';
            app.UITable = uitable(app.BoneQuantMRIUIFigure);
            app.UITable.ColumnName = {'Tissue Volume'; 'Bone Volume'; 'Cortical Volume'; 'Trabecular Volume'; 'Mean Bone Density'; 'Mean Cortical Density'; 'Mean Trabecular Density'; 'Mean GPA Density'; 'Mean Cort Tissue Density'; 'Mean Trabecular Tissue Density'; 'STD Bone Density'; 'STD Cortical Density'; 'STD Trabecular Density'; 'STD GPA Density'; 'STD Cort Tissue'; 'STD Trabecular Tissue'; 'Phantom Density'; 'Noise Density'};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.Image2 = uiimage(app.BoneQuantMRIUIFigure);
            app.Image2.ImageSource = 'topbar_long.png';
            app.Image3 = uiimage(app.BoneQuantMRIUIFigure);
            app.Image3.ImageSource = 'icon-2.png';
            app.BoneQuantMRILabel = uilabel(app.BoneQuantMRIUIFigure);
            app.BoneQuantMRILabel.Text = 'BoneQuantMRI';
            % Show the figure after all components are created
            app.BoneQuantMRIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = qMRIBone

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BoneQuantMRIUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BoneQuantMRIUIFigure)
        end
    end
end